classdef gooey < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        KpEditFieldLabel               matlab.ui.control.Label
        KpVal                          matlab.ui.control.NumericEditField
        KiEditFieldLabel               matlab.ui.control.Label
        KiVal                          matlab.ui.control.NumericEditField
        KdEditFieldLabel               matlab.ui.control.Label
        KdVal                          matlab.ui.control.NumericEditField
        PullConstantsButton            matlab.ui.control.Button
        TargetAngleEditFieldLabel      matlab.ui.control.Label
        TargetAngleEditField           matlab.ui.control.NumericEditField
        COMEditFieldLabel              matlab.ui.control.Label
        COMEditField                   matlab.ui.control.EditField
        EnableMotorButton              matlab.ui.control.Button
        DisableMotorButton             matlab.ui.control.Button
        CurrentPositionEditFieldLabel  matlab.ui.control.Label
        CurrentPositionEditField       matlab.ui.control.NumericEditField
        PushConstantsButton            matlab.ui.control.Button
        SaveConstantsButton            matlab.ui.control.Button
        GetPositionButton              matlab.ui.control.Button
        GetAngleButton                 matlab.ui.control.Button
        VEditFieldLabel                matlab.ui.control.Label
        VEditField                     matlab.ui.control.NumericEditField
        GetButton                      matlab.ui.control.Button
    end

    
    properties (Access = public)
        Property % Description
        ser;
    end
    
    methods (Access = public)
        
        
        function results = func(app)
            
        end
        
        function startupFcn(app)
            app.ser=serialport(app.COMEditField.Value,115200,"DataBits",8,"StopBits",1,"Parity","even");
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: COMEditField
        function COMEditFieldValueChanged(app, event)
            value = app.COMEditField.Value;
            global s
            ser=serialport(value,115200,"DataBits",8,"StopBits",1,"Parity","even");
            s=ser;
            pause(3);
        end

        % Button pushed function: PullConstantsButton
        function PullConstantsButtonPushed(app, event)
             %opens serial port
            global s
            %ser=serialport(app.COMEditField.Value,115200,"DataBits",8,"StopBits",1,"Parity","even");
            %creates array
            pause(1);
            A=[hex2dec('55') hex2dec('AA') 1 double('c')];
            %A=char(sscanf('55 AA 02 50 01','%x'));  
            display(A);
            write(s,A,'uint8');
            %displays what is read back
            %pause(3);
            data=read(s,10,'uint8'); 
            display(data);          
            %display(hex2dec(data(1)));
            app.KpVal.Value=data(5)./(1000);
            app.KiVal.Value=data(7)./(1000);
            app.KdVal.Value=data(9)./(1000);
            %clear s;
            flush(s);
            %clear app.s;
        end

        % Button pushed function: EnableMotorButton
        function EnableMotorButtonPushed(app, event)
            %opens serial port 
            global s
            %s=serialport(app.COMEditField.Value,115200,"DataBits",8,"StopBits",1,"Parity","even");
            %creates array
            pause(1);
            A=[hex2dec('55') hex2dec('AA') 2 double('P') 1];
            %A=char(sscanf('55 AA 02 50 01','%x'));  
            display(A);
            write(s,A,'uint8');
            %displays what is read back
            display(readline(s));
            %clear s;
            flush(s);
            clear s;
        end

        % Button pushed function: DisableMotorButton
        function DisableMotorButtonPushed(app, event)
             global s
            %opens serial port
            %s=serialport(app.COMEditField.Value,115200,"DataBits",8,"StopBits",1,"Parity","even");
            %creates array
            pause(1);
            A=[hex2dec('55') hex2dec('AA') 2 double('P') 0]; 
            display(A);
            write(s,A,'uint8');
            display(readline(s));
            %clear s;
            flush(s);
            clear s;
        end

        % Value changed function: KpVal
        function KpChange(app, event)
            value = app.KpVal.Value;
            
        end

        % Value changed function: KiVal
        function KiChange(app, event)
            value = app.KiVal.Value;
            
        end

        % Value changed function: KdVal
        function KdChange(app, event)
            value = app.KdVal.Value;
            
        end

        % Value changed function: TargetAngleEditField
        function TargetAngleEditFieldValueChanged(app, event)
            value = app.TargetAngleEditField.Value;
            global s
            %creates array
            pause(1);
            A=[hex2dec('55') hex2dec('AA') 2 double('T') value]; 
            display(A);
            write(s,A,'uint8');
            display(readline(s));
            %clear s;
            flush(s);
            clear s;
            
        end

        % Value changed function: CurrentPositionEditField
        function CurrentPositionEditFieldValueChanged(app, event)
            int(value) = app.CurrentPositionEditField.Value;
            
        end

        % Button pushed function: PushConstantsButton
        function PushConstantsButtonPushed(app, event)
              global s
            pause(1);
            %0x55 0xAA 0x07 0x43 0x02 0x12 0x00 0x32 0x00 0x82
            kp=(app.KpVal.Value).*(10^3);
            ki=(app.KiVal.Value).*(10^3);
            kd=(app.KdVal.Value).*(10^3);
           
            
            A=[hex2dec('55') hex2dec('AA') 7 double('C') kp 0 ki 0 kd 0]; 
            %A=char(sscanf('55 AA 02 50 00','%x'));     
             display(A);
            write(s,A,'uint8');
            display(readline(s));
            flush(s);
            clear s;
        end

        % Button pushed function: SaveConstantsButton
        function SaveConstantsButtonPushed(app, event)
             global s
            %creates array
            A=[hex2dec('55') hex2dec('AA') 1 double('S')]; 
            display(A);
            pause(3);
            write(s,A,'uint8');
            display(readline(s));
            %clear s;
            flush(s);
            clear s;
        end

        % Button pushed function: GetButton
        function GetButtonPushed(app, event)
            global s
            A=[hex2dec('55') hex2dec('AA') 1 double('v')]; 
            display(A);
            pause(1);
            write(s,A,'uint8');
            data=(read(s,6,'uint8'));
            display(data);
            app.VEditField.Value=(data(6).*(.0049));
            %clear s;
            flush(s);
        end

        % Value changed function: VEditField
        function VEditFieldValueChanged(app, event)
            value = app.VEditField.Value;
            
        end

        % Button pushed function: GetAngleButton
        function GetAngleButtonPushed(app, event)
             global s
            A=[hex2dec('55') hex2dec('AA') 1 double('t')]; 
            display(A);
            pause(1);
            write(s,A,'uint8');
            data=(read(s,5,'uint8'));
            display(data);
            app.TargetAngleEditField.Value=data(5);
            %clear s;
            flush(s);
            %clear s;
        end

        % Button pushed function: GetPositionButton
        function GetPositionButtonPushed(app, event)
             global s
            A=[hex2dec('55') hex2dec('AA') 1 double('s')]; 
            display(A);
            pause(1);
            write(s,A,'uint8');
            data=(read(s,5,'uint8'));
            display(data);
            app.CurrentPositionEditField.Value=data(5);
            %clear s;
            flush(s);
            %clear s;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.KpEditFieldLabel = uilabel(app.UIFigure);
            app.KpEditFieldLabel.Text = 'Kp';
            app.KpVal = uieditfield(app.UIFigure, 'numeric');
            app.KpVal.ValueChangedFcn = createCallbackFcn(app, @KpChange, true);
            app.KiEditFieldLabel = uilabel(app.UIFigure);
            app.KiEditFieldLabel.Text = 'Ki';
            app.KiVal = uieditfield(app.UIFigure, 'numeric');
            app.KiVal.ValueChangedFcn = createCallbackFcn(app, @KiChange, true);
            app.KdEditFieldLabel = uilabel(app.UIFigure);
            app.KdEditFieldLabel.Text = 'Kd';
            app.KdVal = uieditfield(app.UIFigure, 'numeric');
            app.KdVal.ValueChangedFcn = createCallbackFcn(app, @KdChange, true);
            app.PullConstantsButton = uibutton(app.UIFigure, 'push');
            app.PullConstantsButton.ButtonPushedFcn = createCallbackFcn(app, @PullConstantsButtonPushed, true);
            app.PullConstantsButton.Text = 'Pull Constants';
            app.TargetAngleEditFieldLabel = uilabel(app.UIFigure);
            app.TargetAngleEditFieldLabel.Text = 'Target Angle';
            app.TargetAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.TargetAngleEditField.ValueChangedFcn = createCallbackFcn(app, @TargetAngleEditFieldValueChanged, true);
            app.COMEditFieldLabel = uilabel(app.UIFigure);
            app.COMEditFieldLabel.Text = 'COM';
            app.COMEditField = uieditfield(app.UIFigure, 'text');
            app.COMEditField.ValueChangedFcn = createCallbackFcn(app, @COMEditFieldValueChanged, true);
            app.COMEditField.Value = 'COM#';
            app.EnableMotorButton = uibutton(app.UIFigure, 'push');
            app.EnableMotorButton.ButtonPushedFcn = createCallbackFcn(app, @EnableMotorButtonPushed, true);
            app.EnableMotorButton.Text = 'Enable Motor';
            app.DisableMotorButton = uibutton(app.UIFigure, 'push');
            app.DisableMotorButton.ButtonPushedFcn = createCallbackFcn(app, @DisableMotorButtonPushed, true);
            app.DisableMotorButton.Text = 'Disable Motor';
            app.CurrentPositionEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentPositionEditFieldLabel.Text = 'Current Position';
            app.CurrentPositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentPositionEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentPositionEditFieldValueChanged, true);
            app.PushConstantsButton = uibutton(app.UIFigure, 'push');
            app.PushConstantsButton.ButtonPushedFcn = createCallbackFcn(app, @PushConstantsButtonPushed, true);
            app.PushConstantsButton.Text = 'Push Constants';
            app.SaveConstantsButton = uibutton(app.UIFigure, 'push');
            app.SaveConstantsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConstantsButtonPushed, true);
            app.SaveConstantsButton.Text = 'Save Constants';
            app.GetPositionButton = uibutton(app.UIFigure, 'push');
            app.GetPositionButton.ButtonPushedFcn = createCallbackFcn(app, @GetPositionButtonPushed, true);
            app.GetPositionButton.Text = 'Get Position';
            app.GetAngleButton = uibutton(app.UIFigure, 'push');
            app.GetAngleButton.ButtonPushedFcn = createCallbackFcn(app, @GetAngleButtonPushed, true);
            app.GetAngleButton.Text = 'Get Angle';
            app.GetButton = uibutton(app.UIFigure, 'push');
            app.GetButton.ButtonPushedFcn = createCallbackFcn(app, @GetButtonPushed, true);
            app.GetButton.Text = 'Get';
            app.VEditFieldLabel = uilabel(app.UIFigure);
            app.VEditFieldLabel.Text = 'V';
            app.VEditField = uieditfield(app.UIFigure, 'numeric');
            app.VEditField.ValueChangedFcn = createCallbackFcn(app, @VEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gooey

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end